classdef Gompz_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NoDataCheckBox           matlab.ui.control.CheckBox
        T_lagSlider              matlab.ui.control.Slider
        T_lagSliderLabel         matlab.ui.control.Label
        mu_relSlider             matlab.ui.control.Slider
        mu_relSliderLabel        matlab.ui.control.Label
        KSlider                  matlab.ui.control.Slider
        KSliderLabel             matlab.ui.control.Label
        XoSlider                 matlab.ui.control.Slider
        X_oSliderLabel           matlab.ui.control.Label
        ExportValuesButton       matlab.ui.control.Button
        InputDataButton          matlab.ui.control.Button
        XX_oKexpleftexpleftfracemu_relKrightT_lagt1rightLabel  matlab.ui.control.Label
        GompertzPlaygroundLabel  matlab.ui.control.Label
        GrowthRate               matlab.ui.control.UIAxes
        Profile                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        X_data % Time Elapsed
        Y_data % Growth Data
        Xmu % Averaged Time Values
        Ymu % Y growth rates
        X_GM % Times for Gompertz Model
        Y_GM % Estimates for Growth Data
        Xmu_GM % Growth Rate Times from Gompertz
        Ymu_GM % Growth Rates from Gompertz
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InputDataButton
        function InputDataButtonPushed(app, event)
            dataName = input("From which data set do you want to plot?\n","s");
            data = evalin('base',dataName);
            runNum = input("Which screening test do you want to plot?\n");
            if runNum <=0 || runNum > max(size(data))
                 disp('Outside Bounds. Please try again.')
                 return
            else
                 runNum = int8(runNum);
                 app.NoDataCheckBox.Value = 0;
                 app.X_data = data{runNum,2};
                 app.Y_data = data{runNum,1};
                 plot(app.Profile,app.X_data,app.Y_data,'ko');
                 app.Xmu = (app.X_data(2:end) + app.X_data(1:end-1))/2;
                 app.Ymu = log(app.Y_data(2:end,:)./app.Y_data(1:end-1,:))./(app.X_data(2:end)-app.X_data(1:end-1));
                 plot(app.GrowthRate,app.Xmu,app.Ymu,'ko')

                 % Adjust the sliders appropriately
                 app.XoSlider.Limits = [0,2*max(app.Y_data(1,:))];
                 app.XoSlider.Value = max(app.Y_data(1,:));
                 app.KSlider.Limits = [max(app.Y_data,[],'all') - 1, max(app.Y_data,[],'all') + 1];
                 app.KSlider.Value = max(app.Y_data,[],'all');

                 % Make the Gompertz models
                 app.X_GM = linspace(0,max(app.X_data),500);
                 app.Y_GM = app.XoSlider.Value + app.KSlider.Value*exp(-exp((exp(1)*app.mu_relSlider.Value/app.KSlider.Value).*(app.T_lagSlider.Value - app.X_GM) + 1));
                 app.Xmu_GM = (app.X_GM(2:end) + app.X_GM(1:end-1))/2;
                 app.Ymu_GM = log(app.Y_GM(2:end)./app.Y_GM(1:end-1))./(app.X_GM(2:end)-app.X_GM(1:end-1));
                 
                 % Plot the results
                 hold(app.Profile,'on');
                 plot(app.Profile,app.X_GM,app.Y_GM,'r-',LineWidth=2);
                 hold(app.Profile,'off');

                 hold(app.GrowthRate,'on');
                 plot(app.GrowthRate,app.Xmu_GM,app.Ymu_GM,'r-',LineWidth=2);
                 hold(app.GrowthRate,'off');
                 
            end
        end

        % Value changing function: T_lagSlider
        function T_lagSliderValueChanging(app, event)
            changingValue = event.Value;
            % Adjust the Gompertz model
            app.Y_GM = app.XoSlider.Value + app.KSlider.Value*exp(-exp((exp(1)*app.mu_relSlider.Value/app.KSlider.Value).*(changingValue - app.X_GM) + 1));
            app.Ymu_GM = log(app.Y_GM(2:end)./app.Y_GM(1:end-1))./(app.X_GM(2:end)-app.X_GM(1:end-1));

            % Plot the new results
            plot(app.Profile,app.X_GM,app.Y_GM,'r-',LineWidth=2);
            if app.NoDataCheckBox.Value == 0
                hold(app.Profile,'on');
                plot(app.Profile,app.X_data,app.Y_data,'ko');
                hold(app.Profile,'off');
            end

            plot(app.GrowthRate,app.Xmu_GM,app.Ymu_GM,'r-',LineWidth=2);
            if app.NoDataCheckBox.Value == 0
                hold(app.GrowthRate,'on');
                plot(app.GrowthRate,app.Xmu,app.Ymu,'ko')
                hold(app.GrowthRate,'off');
            end
        end

        % Value changing function: mu_relSlider
        function mu_relSliderValueChanging(app, event)
            changingValue = event.Value;
            % Adjust the Gompertz Model
            app.Y_GM = app.XoSlider.Value + app.KSlider.Value*exp(-exp((exp(1)*changingValue/app.KSlider.Value).*(app.T_lagSlider.Value - app.X_GM) + 1));
            app.Ymu_GM = log(app.Y_GM(2:end)./app.Y_GM(1:end-1))./(app.X_GM(2:end)-app.X_GM(1:end-1));

            % Plot the new results
            plot(app.Profile,app.X_GM,app.Y_GM,'r-',LineWidth=2);
            if app.NoDataCheckBox.Value == 0
                hold(app.Profile,'on');
                plot(app.Profile,app.X_data,app.Y_data,'ko');
                hold(app.Profile,'off');
            end

            plot(app.GrowthRate,app.Xmu_GM,app.Ymu_GM,'r-',LineWidth=2);
            if app.NoDataCheckBox.Value == 0
                hold(app.GrowthRate,'on');
                plot(app.GrowthRate,app.Xmu,app.Ymu,'ko')
                hold(app.GrowthRate,'off');
            end
        end

        % Value changing function: KSlider
        function KSliderValueChanging(app, event)
            changingValue = event.Value;
            % Adjust the Gompertz Model
            app.Y_GM = app.XoSlider.Value + changingValue*exp(-exp((exp(1)*app.mu_relSlider.Value/changingValue).*(app.T_lagSlider.Value - app.X_GM) + 1));
            app.Ymu_GM = log(app.Y_GM(2:end)./app.Y_GM(1:end-1))./(app.X_GM(2:end)-app.X_GM(1:end-1));

            % Plot the new results
            plot(app.Profile,app.X_GM,app.Y_GM,'r-',LineWidth=2);
            if app.NoDataCheckBox.Value == 0
                hold(app.Profile,'on');
                plot(app.Profile,app.X_data,app.Y_data,'ko');
                hold(app.Profile,'off');
            end

            plot(app.GrowthRate,app.Xmu_GM,app.Ymu_GM,'r-',LineWidth=2);
            if app.NoDataCheckBox.Value == 0
                hold(app.GrowthRate,'on');
                plot(app.GrowthRate,app.Xmu,app.Ymu,'ko')
                hold(app.GrowthRate,'off');
            end
        end

        % Value changing function: XoSlider
        function XoSliderValueChanging(app, event)
            changingValue = event.Value;
            % Adjust the Gompertz Model
            app.Y_GM = changingValue + app.KSlider.Value*exp(-exp((exp(1)*app.mu_relSlider.Value/app.KSlider.Value).*(app.T_lagSlider.Value - app.X_GM) + 1));
            app.Ymu_GM = log(app.Y_GM(2:end)./app.Y_GM(1:end-1))./(app.X_GM(2:end)-app.X_GM(1:end-1));

            % Plot the new results
            plot(app.Profile,app.X_GM,app.Y_GM,'r-',LineWidth=2);
            if app.NoDataCheckBox.Value == 0
                hold(app.Profile,'on');
                plot(app.Profile,app.X_data,app.Y_data,'ko');
                hold(app.Profile,'off');
            end

            plot(app.GrowthRate,app.Xmu_GM,app.Ymu_GM,'r-',LineWidth=2);
            if app.NoDataCheckBox.Value == 0
                hold(app.GrowthRate,'on');
                plot(app.GrowthRate,app.Xmu,app.Ymu,'ko')
                hold(app.GrowthRate,'off');
            end
        end

        % Button pushed function: ExportValuesButton
        function ExportValuesButtonPushed(app, event)
            initGuess = [app.XoSlider.Value,app.KSlider.Value,app.T_lagSlider.Value,app.mu_relSlider.Value];
            disp('initGuess --> [Xo, K, Tlag, mu_rel]')
            assignin('base','initGuess',initGuess);
        end

        % Value changed function: NoDataCheckBox
        function NoDataCheckBoxValueChanged(app, event)
            value = app.NoDataCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Profile = uiaxes(app.UIFigure);
            title(app.Profile, 'Growth Plot')
            xlabel(app.Profile, 'Time Elapsed (hrs)')
            ylabel(app.Profile, '[Biomass] (gDCW/L)')
            zlabel(app.Profile, 'Z')
            app.GrowthRate = uiaxes(app.UIFigure);
            title(app.GrowthRate, 'Growth Rates')
            xlabel(app.GrowthRate, 'Time Elapsed (hrs)')
            ylabel(app.GrowthRate, 'Approx. Growth Rate (1/hr)')
            zlabel(app.GrowthRate, 'Z')
            app.GompertzPlaygroundLabel = uilabel(app.UIFigure);
            app.GompertzPlaygroundLabel.Text = 'Gompertz Playground';
            app.XX_oKexpleftexpleftfracemu_relKrightT_lagt1rightLabel = uilabel(app.UIFigure);
            app.XX_oKexpleftexpleftfracemu_relKrightT_lagt1rightLabel.Interpreter = 'latex';
            app.XX_oKexpleftexpleftfracemu_relKrightT_lagt1rightLabel.Text = '$X = X_o + K\exp\left(-\exp\left(\frac{e*\mu_{rel}}{K}\right)(T_{lag}-t)+1\right)$';
            app.InputDataButton = uibutton(app.UIFigure, 'push');
            app.InputDataButton.ButtonPushedFcn = createCallbackFcn(app, @InputDataButtonPushed, true);
            app.InputDataButton.Text = 'Input Data';
            app.ExportValuesButton = uibutton(app.UIFigure, 'push');
            app.ExportValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ExportValuesButtonPushed, true);
            app.ExportValuesButton.Text = 'Export Values';
            app.X_oSliderLabel = uilabel(app.UIFigure);
            app.X_oSliderLabel.Interpreter = 'latex';
            app.X_oSliderLabel.Text = 'X_o';
            app.XoSlider = uislider(app.UIFigure);
            app.XoSlider.Limits = [0 0.1];
            app.XoSlider.ValueChangingFcn = createCallbackFcn(app, @XoSliderValueChanging, true);
            app.XoSlider.Value = 0.02;
            app.KSliderLabel = uilabel(app.UIFigure);
            app.KSliderLabel.Interpreter = 'latex';
            app.KSliderLabel.Text = 'K';
            app.KSlider = uislider(app.UIFigure);
            app.KSlider.Limits = [0 10];
            app.KSlider.ValueChangingFcn = createCallbackFcn(app, @KSliderValueChanging, true);
            app.KSlider.Value = 5;
            app.mu_relSliderLabel = uilabel(app.UIFigure);
            app.mu_relSliderLabel.Interpreter = 'latex';
            app.mu_relSliderLabel.Text = '\mu_{rel}';
            app.mu_relSlider = uislider(app.UIFigure);
            app.mu_relSlider.Limits = [0 0.5];
            app.mu_relSlider.ValueChangingFcn = createCallbackFcn(app, @mu_relSliderValueChanging, true);
            app.mu_relSlider.Value = 0.15;
            app.T_lagSliderLabel = uilabel(app.UIFigure);
            app.T_lagSliderLabel.Interpreter = 'latex';
            app.T_lagSliderLabel.Text = 'T_{lag}';
            app.T_lagSlider = uislider(app.UIFigure);
            app.T_lagSlider.Limits = [0 50];
            app.T_lagSlider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50];
            app.T_lagSlider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50'};
            app.T_lagSlider.ValueChangingFcn = createCallbackFcn(app, @T_lagSliderValueChanging, true);
            app.T_lagSlider.Value = 10;
            app.NoDataCheckBox = uicheckbox(app.UIFigure);
            app.NoDataCheckBox.ValueChangedFcn = createCallbackFcn(app, @NoDataCheckBoxValueChanged, true);
            app.NoDataCheckBox.Text = 'No Data';
            app.NoDataCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gompz_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end